classdef AdvancedSetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CaxisSlider                   matlab.ui.control.RangeSlider
        CaxisSliderLabel              matlab.ui.control.Label
        regularizationEditField       matlab.ui.control.NumericEditField
        regularizationEditFieldLabel  matlab.ui.control.Label
        OkButton                      matlab.ui.control.Button
        ApplyButton                   matlab.ui.control.Button
        threshhomerEditField          matlab.ui.control.NumericEditField
        threshhomerEditFieldLabel     matlab.ui.control.Label
        pruneEditField                matlab.ui.control.NumericEditField
        pruneEditFieldLabel           matlab.ui.control.Label
        caxisvalueEditField           matlab.ui.control.NumericEditField
        caxisvalueEditFieldLabel      matlab.ui.control.Label
        BetaEditField                 matlab.ui.control.NumericEditField
        BetaEditFieldLabel            matlab.ui.control.Label
        AlphaEditField                matlab.ui.control.NumericEditField
        AlphaEditFieldLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, CfgIn, applyCb)
            app.MainApp = mainApp;
            app.cfg = cfgIn;
            app.ApplyCb = applyCb;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            ApplyButtonPushed(app, event);
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            ApplyButtonPushed(app, event);
            delete(app);
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            value = app.AlphaEditFieldValueChanged.Value;

            if ~(isnumeric(value)) || isempty(value)
                uialert(app.UIFigure, 'Please enter your number!', 'Error');
                app.AlphaEditField.Value = [];
                return;
            end

            if value <= 0 || value >= 1
                uialert(app.UIFigure, 'Alpha have to in range (0,1)', 'Error');
                app.AlphaEditField.Value = [];
                return;
            end;

            app.cfg.alpha = value;

        end

        % Value changed function: caxisvalueEditField
        function caxisvalueEditFieldValueChanged(app, event)
            value = app.caxisvalueEditField.Value;
            
            if ~(isnumeric(value)) || isempty(value)
                uialert(app.UIFigure, 'Pleae enter number~', 'Error');
                app.AlphaEditField.Value = [];
                return
            end

           
        end

        % Value changed function: CaxisSlider
        function CaxisSliderValueChanged(app, event)
            
            value = app.CaxisSlider.Value;

            if ~isnumeric(value) || isempty(value)
                uialert(app.UIFigure, 'Wrong format');
                return;
            end
            value = value * 1e-6;
            app.cfg.caxis_value = [-value value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AlphaEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.caxisvalueEditFieldLabel = uilabel(app.UIFigure);
            app.caxisvalueEditFieldLabel.Text = 'caxis value';
            app.caxisvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.caxisvalueEditField.ValueChangedFcn = createCallbackFcn(app, @caxisvalueEditFieldValueChanged, true);
            app.pruneEditFieldLabel = uilabel(app.UIFigure);
            app.pruneEditFieldLabel.Text = 'prune';
            app.pruneEditField = uieditfield(app.UIFigure, 'numeric');
            app.threshhomerEditFieldLabel = uilabel(app.UIFigure);
            app.threshhomerEditFieldLabel.Text = 'thresh homer';
            app.threshhomerEditField = uieditfield(app.UIFigure, 'numeric');
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.regularizationEditFieldLabel = uilabel(app.UIFigure);
            app.regularizationEditFieldLabel.Text = 'regularization';
            app.regularizationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CaxisSliderLabel = uilabel(app.UIFigure);
            app.CaxisSliderLabel.Text = 'Caxis';
            app.CaxisSlider = uislider(app.UIFigure, 'range');
            app.CaxisSlider.Limits = [-1 1];
            app.CaxisSlider.ValueChangedFcn = createCallbackFcn(app, @CaxisSliderValueChanged, true);
            app.CaxisSlider.Value = [0 0.5];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdvancedSetting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end