classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SignalListBox          matlab.ui.control.ListBox
        PlotButton             matlab.ui.control.Button
        ChannelDropDown        matlab.ui.control.DropDown
        ChannelDropDownLabel   matlab.ui.control.Label
        PlotTypeButtonGroup    matlab.ui.container.ButtonGroup
        ConcButton             matlab.ui.control.RadioButton
        ODButton               matlab.ui.control.RadioButton
        RawdataButton          matlab.ui.control.RadioButton
        ListsnrifListBox       matlab.ui.control.ListBox
        ListsnrifListBoxLabel  matlab.ui.control.Label
        AdvancedsettingButton  matlab.ui.control.Button
        SpatiallyregularizationCheckBox  matlab.ui.control.CheckBox
        UsedrifterCheckBox     matlab.ui.control.CheckBox
        UseSSavgCheckBox       matlab.ui.control.CheckBox
        ListBoxSubject         matlab.ui.control.ListBox
        DatafilesLabel         matlab.ui.control.Label
        RunImageReconExp       matlab.ui.control.Button
        DeviceDropDown         matlab.ui.control.DropDown
        DeviceLabel            matlab.ui.control.Label
        BrowseData             matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Property % Description
        ds % data source
        cache % current temp
        sel % user selection
        cfg
    end
    
    methods (Access = private)
       
        %% function for advance settings
        function onAdvancedApply(app, newCfg) %% When user closes the adv setting window, apply changes
            app.cfg = newCfg;
            app.AdvancedsettingButton.Enable = "on";
        end


        %% This function update the "Signal List" box on the UI
        % It check which plot type the user selected Raw/OD/Conc.
        % e.g: user select Conc -> it show (like Hbo/HbR/HbT)
        % After creating this list, the function updates the SignalListBox on the UI 
        function updateSignalList(app)

            plotType = app.PlotTypeButtonGroup.SelectedObject.Text;
            app.ds.waveLength = app.ds.snirf.probe.wavelengths;
            items = {};
            
            % user select Raw/OD -> it will show (like 670nm, 850nm)
            if strcmp(plotType, 'Raw data') || strcmp(plotType, 'OD')
                for i = 1:numel(app.ds.waveLength)
                    items{i} = sprintf('%dnm', round(app.ds.waveLength(i)));
                end
                
            % user select Conc -> it show (like Hbo/HbR/HbT)
            elseif strcmp(plotType, 'Conc')
                items{1} = 'HbO';
                items{2} = 'HbR';
                items{3} = 'HbT';
            end


            % update Signal List Box
            app.SignalListBox.Items = items;
            app.SignalListBox.Value = items{1};
            drawnow;
        end
        

        %%  Parse channel label text "TxX–RxY" into numeric ID
        function [s,d] = parseChannelLabel(~,label)
            % extract 2 numbers: after 'Tx' and after 'Rx', with any non-digit separator between
            tok = regexp(label,'Tx(\d+)[^\d]+Rx(\d+)','tokens','once');
            s = str2double(tok{1});
            d = str2double(tok{2});
        end


        %% Parse the integer number before 'nm'
        function waveLengthIdx = nmLabelToIndex(app, nmLabel)
            waveLengthIdx = [];
            nm = sscanf(nmLabel, '%dnm');
            if isempty(nm) || isempty(app.ds.wls)
                waveLengthIdx = [];
                return;
            end
            % Find the first index where wavelength equals that number
            waveLengthIdx = find(app.ds.wls == nm, 1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % DEFAULT DEFAULT


            appDir  = fileparts(mfilename('fullpath')); % .../SSDOT-main/GUI
            rootDir = fileparts(appDir); % .../SSDOT-main
        

            addpath(genpath(fullfile(rootDir,'code')));
            addpath(genpath(fullfile(rootDir,'homer_function')));

            % DEFAULT VAR, user can change these parameter on GUI
            app.cfg.datapath    = '';
            app.cfg.av_data_path= '';

            app.cfg.alpha = 1e-2;
            app.cfg.beta = 0;
            app.cfg.regularization = 1;
            app.cfg.caxis_value = [-1 1]*1e-6;
            app.cfg.loc_line = 'off';
            
            app.cfg.prune = 5;
            app.cfg.with_scalp  = 0;
            app.cfg.prune_range = [0 10000000];

            app.cfg.SS_flag = 1;
            
            app.cfg.drift_flag = 1;
            app.cfg.driftOrder = 3;

            app.cfg.bi = 1;

            app.cfg.trange = [-2, 18];
            app.cfg.basis = 2;
            app.cfg.gamma_parameters = [0.1 3 7 0.5 3 7];
            app.cfg.prune_SD = [0 45];
            app.cfg.SS_mode = 2;
            app.cfg.AR_model = 1;

            app.cfg.rhoSD_ssThresh.homer = 20;
            app.cfg.rhoSD_ssThresh.av = 7;

            app.cfg.n_batch_brain = 1;
            app.cfg.n_batch_scalp = 1;
            app.cfg.with_scalp = 0;

            app.cfg.n_vertex_brain = 20004;


            % SYNC UI
            app.DevieDropDown.Value = upper(app.cfg.device);
            app.UseSSavgCheckBox.Value = logical(app.cfg.SS_flag);
            app.UsedrifterCheckBox.Value = logical(app.cfg.drift_flag);
            app.SpatiallyregularizationCheckBox.Value = logical(app.cfg.spatially_regu);


            % Other
            app.TextArea.Editable = 'off';
    
        end

        % Button pushed function: BrowseData
        function BrowseDataButtonPushed(app, event)
            %% Open a folder selection to allow user to choose(browse) the data folder
            %% Then after select, show the subjects list with folder that match the naming "Subj*"

            folderPath = uigetdir(pwd, 'Select the exp_data folder');
            
            if isequal(folderPath, 0)
                return;
            end

            app.cfg.datapath = folderPath;
            app.cfg.av_data_path = folderPath;
            app.cfg.savepath = fullfile(folderPath, 'bothConditions');
            disp(['Data folder set to: ', folderPath]);

            folders = dir(fullfile(folderPath,'Subj*'));
            names = {folders([folders.isdir]).name};
            names = setdiff(names, {'.', '..'});

            if isempty(names)
                app.ListBoxSubject.Items = {'(Not found Subj* folder)'};
            else
                app.ListBoxSubject.Items = strcat("📁 ", string(names));
            end

            
        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
            % when user selects between gpu or cpu, store/load the choice

            value = app.DeviceDropDown.Value;

            app.cfg.device = lower(value);

            disp(['Device set to: ', app.cfg.device]);
            
        end

        % Button pushed function: RunImageReconExp
        function RunImageReconExpButtonPushed(app, event)
            %% nothing...


        end

        % Button pushed function: AdvancedsettingButton
        function AdvancedsettingButtonPushed(app, event)
            % Open the advanced settings window
            % allow user change some parameters

            app.AdvApp = AdvancedSetting(app, app.cfg, @app.onAdvancedApply);
            app.AdvApp.UIFigure.WindowStyle = 'modal';
            app.AdvancedButton.Enable = "off";
        end

        % Value changed function: UseSSavgCheckBox
        function UseSSavgCheckBoxValueChanged(app, event)
            % update SS enable
            value = app.UseSSavgCheckBox.Value;
            app.cfg.SS_flag = double(value);
        end

        % Value changed function: UsedrifterCheckBox
        function UsedrifterCheckBoxValueChanged(app, event)
            % update Drifter enable
            value = app.UsedrifterCheckBox.Value;
            app.cfg.drift_flag = double(value);
        end

        % Value changed function: SpatiallyregularizationCheckBox
        function SpatiallyregularizationCheckBoxValueChanged(app, event)
            %spatially regularization checkbox
            value = app.SpatiallyregularizationCheckBox.Value;
            app.cfg.spatially_regu = double(value);
        end

        % Double-clicked callback: ListBoxSubject
        function ListBoxSubjectDoubleClicked(app, event)
            % When user double clicked a subject folder in the list
            % scans the subject folder for .snirf fiels
            % updates ListSnirfListBox with found SNIRF files

            rawVal  = string(app.ListBoxSubject.Value);
            subj    = regexprep(rawVal, '^\s*📁\s*', '');
        
            if ~isfield(app.cfg,'datapath') || ~isfolder(app.cfg.datapath)
                uialert(app.UIFigure,'browse to select exp_data folder first.','Eroor');
                app.ListsnrifListBox.Items = {};
                return
            end
        
            subjPath = fullfile(app.cfg.datapath, subj);
            % find all snirf files inside subject folder
            S = dir(fullfile(subjPath, '*.snirf'));
        
            if isempty(S)
                app.ListsnrifListBox.Items = {'(No .snirf found)'};
                app.ListsnrifListBox.Value = '(No .snirf found)';
            else
                app.ListsnrifListBox.Items = {S.name}; %%update snirf List Box, show all snirf files
                app.ListsnrifListBox.Value = S(1).name;
            end
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %% Wrap everything to check before hit plot button
            if isempty(app.ds) || isempty(app.ds.snirf) || isempty(app.ds.snirf.data)
                uialert(app.UIFigure, 'Please load a SNIRF first.', 'Warning'); return;
            end
            if isempty(app.ChannelDropDown.Value) || startsWith(string(app.ChannelDropDown.Value),'(no ')
                uialert(app.UIFigure, 'Please select channel', 'Warning'); return;
            end
            if isempty(app.SignalListBox.Value) || startsWith(string(app.SignalListBox.Value),'(no ')
                uialert(app.UIFigure, 'Please select wavelength', 'Warning'); return;
            end

            % Selection from user
            chLabel = app.ChannelDropDown.Value; % 'TxX–RxY'
            sigLabel = app.SignalListBox.Value;  % '670nm'/Hbr
            [s,d]   = app.parseChannelLabel(chLabel);

            % Preload raw data and time (Raw/OD use this)
            dataMat = app.ds.snirf.data.GetDataTimeSeries(); % array[N][M]
            timeVec = app.ds.snirf.data.GetTime(); % array[N][1]

            % Build arrays of source, detector, wavelength index for raw/od
            % look up column
            ml = app.ds.snirf.data.measurementList;
            numMeasurements = numel(ml);
            sourceIdx = zeros(numMeasurements, 1);
            detectorIdx = zeros(numMeasurements, 1);
            waveLengthIdx = ones(numMeasurements,1); % let be 1 for

            if ~isempty(ml) && isprop(ml(1),'wavelengthIndex')
                for i = 1:numMeasurements
                    waveLengthIdx(i) = ml(i).wavelengthIndex;
                end
            end

            for i = 1:numMeasurements
                sourceIdx(i) = ml(i).sourceIndex;
                detectorIdx(i) = ml(i).detectorIndex;
            end


            plotType = app.PlotTypeButtonGroup.SelectedObject.Text;
            cla(app.UIAxes);

            switch plotType
                case 'Raw data'
                    % wavelength index only for raw/OD
                    wlIdx = app.nmLabelToIndex(sigLabel);
                    
                    % find column for (s,d,wlIdx)
                    col = find(sourceIdx == s & detectorIdx == d & waveLengthIdx == wlIdx, 1, 'first');

                    % Plot
                    plot(app.UIAxes, timeVec, dataMat(:,col), 'LineWidth', 1.2);
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'Intensity (a.u.)');
                    title(app.UIAxes, sprintf('Raw — Tx%d–Rx%d — %s', s, d, sigLabel));


                case 'OD'
                    wlIdx = app.nmLabelToIndex(sigLabel);

                    col = find(sourceIdx == s & detectorIdx == d & waveLengthIdx == wlIdx, 1, 'first');

                    % Converting intensity to optical density
                    app.cache.dod = hmrR_Intensity2OD(app.ds.snirf.data);
                    % filter by using a band-pass filter [0.01-0.1] Hz
                    app.cache.dod = hmrR_BandpassFilt(app.cache.dod, 0.01, 0.1);

                    % Plot
                    plot(app.UIAxes, app.cache.dod.time, app.cache.dod.dataTimeSeries(:,col), 'LineWidth', 1.2);
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'I dont know unit for Y-axis here');
                    title(app.UIAxes, sprintf('Optical Density — Tx%d–Rx%d — %s', s, d, sigLabel));


                case 'Conc'
                    % Convert to optical density
                    app.cache.dod = hmrR_Intensity2OD(app.ds.snirf.data);

                    % filter
                    app.cache.dod = hmrR_BandpassFilt(app.cache.dod, 0.01, 0.1);

                    % Convert optical density to concentration
                    dpf = 6.06;
                    app.cache.dc = hmrR_OD2Conc(app.cache.dod, app.ds.snirf.probe, [dpf, dpf]);

                    % Find first entry for this Tx/Rx (assume HbO) then slice HbO..HbT
                    HbO_id = [];
                    for idx = 1:numel(app.cache.dc.measurementList)
                        s_id0 = app.cache.dc.measurementList(1, idx).sourceIndex;
                        d_id0 = app.cache.dc.measurementList(1, idx).detectorIndex;
                        if (s_id0 == s && d_id0 == d)
                            HbO_id = idx;
                            break
                        end
                    end


                    app.cache.dc.measurementList = app.cache.dc.measurementList(1, HbO_id:HbO_id+2);
                    app.cache.dc.dataTimeSeries  = app.cache.dc.dataTimeSeries(:, HbO_id:HbO_id+2);

                    % pick which to plot 
                    selection = lower(sigLabel);
                    if strcmp(selection, 'hbo') , k = 1;
                    elseif strcmp(selection, 'hbr'), k = 2;
                    elseif strcmp(selection, 'hbt'), k = 3;
                    end

                    % Plot
                    %% y value can scale by = 1e6 * app.cache.dc.dataTimeSeries(:,k)
                    plot(app.UIAxes, app.cache.dc.time, app.cache.dc.dataTimeSeries(:, k), 'LineWidth', 1.2);
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes, sprintf('%s (\\M = mol/L', upper(sigLabel)));
                    title(app.UIAxes, sprintf('Conc — %s — Tx%d–Rx%d', upper(sigLabel), s, d));
            end


           app.UIAxes.XTickMode = 'auto';
           app.UIAxes.YTickMode = 'auto';
           grid(app.UIAxes,'on');


            % The code below is old version. I leave it here to look back while rewriting.
            % Nothing important
            % Old code, i leave it here for now for rewrite it again for code above
            % disp(sigLabel);
            % 
            % [s,d] = app.parseChannelLabel(chLabel);
            % wlIdx = app.nmLabelToIndex(sigLabel);
            % 
            % 
            % 
            % % Get data + time
            % dataMat = app.ds.snirf.data.GetDataTimeSeries(); % 
            % timeVec = app.ds.snirf.data.GetTime(); % 
            % 
            % % Find the column for (s,d,wlIdx)
            % ml = app.ds.snirf.data.measurementList;
            % n = numel(ml);
            % S = zeros(n,1);
            % D = zeros(n,1);
            % WL = ones(n,1);
            % if ~isempty(ml) && isprop(ml(1),'wavelengthIndex')
            %     for i=1:n
            %         WL(i) = ml(i).wavelengthIndex;
            %     end
            % end
            % 
            % for i=1:n
            %     S(i) = ml(i).sourceIndex;
            %     D(i) = ml(i).detectorIndex;
            % end
            % 
            % % find first column that matches the requested (s, d, wlIdx)
            % if ~isempty(wlIdx) && isnumeric(wlIdx)
            %     % Raw/OD case -> find column using wavelength
            %     col = find(S==s & D==d & WL==wlIdx, 1, 'first');
            % else
            %     % Conc case -> no wavelength index used
            %     col = [];
            % end
            % 
            % 
            % plotType = app.PlotTypeButtonGroup.SelectedObject.Text;
            % 
            % cla(app.UIAxes);
            % 
            % switch plotType
            %     case 'Raw data'
            %         plot(app.UIAxes, timeVec, dataMat(:,col), 'LineWidth', 1.2);
            %         xlabel(app.UIAxes,'Time (s)');
            %         ylabel(app.UIAxes,'Intensity (a.u.)');
            %         title(app.UIAxes, sprintf('Raw — Tx%d–Rx%d — %s', s, d, sigLabel));
            % 
            % 
            %     case 'OD'
            %         % Converting intensity to optical density
            %         app.cache.dod = hmrR_Intensity2OD(app.ds.snirf.data);
            %         % filter by using a band-pass filter [0.01-0.1] Hz
            %         app.cache.dod = hmrR_BandpassFilt(app.cache.dod, 0.01, 0.1);
            % 
            %         plot(app.UIAxes, app.cache.dod.time, app.cache.dod.dataTimeSeries(:,col), 'LineWidth', 1.2);
            %         xlabel(app.UIAxes,'Time (s)');
            %         ylabel(app.UIAxes,'I dont know unit for Y-axis here');
            %         title(app.UIAxes, sprintf('Optical Density — Tx%d–Rx%d — %s', s, d, sigLabel));
            % 
            %     case 'Conc'
            %         %%nothing for now
            %         % Converting intensity to optical density
            %         app.cache.dod = hmrR_Intensity2OD(app.ds.snirf.data);
            %         % filter
            %         app.cache.dod = hmrR_BandpassFilt(app.cache.dod, 0.01, 0.1);
            % 
            % 
            %         % let dpf = 6.06 default
            %         dpf = 6.06;
            %         % Converting OD to Concentration
            %         app.cache.dc = hmrR_OD2Conc(app.cache.dod, app.ds.snirf.probe, [dpf, dpf]);
            % 
            %         for idx = 1:numel(app.cache.dc.measurementList)
            %             s_id0 = app.cache.dc.measurementList(1, idx).sourceIndex;
            %             d_id0 = app.cache.dc.measurementList(1, idx).detectorIndex;
            %             if (s_id0 == s && d_id0 == d)
            %                 HbO_id = idx;
            %                 break
            %             end
            %         end
            % 
            %         app.cache.dc.measurementList = app.cache.dc.measurementList(1, HbO_id:HbO_id+2);
            %         app.cache.dc.dataTimeSeries  = app.cache.dc.dataTimeSeries(:, HbO_id:HbO_id+2);
            % 
            %         % Pick which to plot based on sigLabel
            %         k = 1;   % default HbO
            % 
            %         disp(sigLabel); % debug
            % 
            %         if strcmp(sigLabel, 'HbO')
            %             k = 1;
            %         elseif strcmp(sigLabel, 'HbR')
            %                 k = 2;
            %         elseif strcmp(sigLabel, 'HbT') 
            %                 k = 3;
            %         end
            % 
            %         % Plot in µM for readability
            %         % yvals = constant(like 1e6) * app.cache.dc.dataTimeSeries(:, k);
            %         plot(app.UIAxes, app.cache.dc.time, app.cache.dc.dataTimeSeries(:, k), 'LineWidth', 1.2);
            %         xlabel(app.UIAxes,'Time (s)');
            %         ylabel(app.UIAxes, sprintf('%s (\\M = mol/L)', sigLabel));
            %         title(app.UIAxes, sprintf('Conc — %s — Tx%d–Rx%d', sigLabel, s, d));
            % end


        end

        % Double-clicked callback: ListsnrifListBox
        function ListsnrifListBoxDoubleClicked(app, event)
            % When use double click a SNIRF file
            % it will immediately load file using SnirfClass()
            %
            rawSubj   = char(app.ListBoxSubject.Value);
            subj      = regexprep(rawSubj, '^\s*📁\s*', '');
            snirfName = char(app.ListsnrifListBox.Value);
            
            filePath = fullfile(app.cfg.datapath, subj, snirfName);
            filePath = char(filePath);
            
            % % debug
            % % fprintf('Datapath = %s\n', app.cfg.datapath);
            % % fprintf('Subject = %s\n', subj);
            % % fprintf('SNIRF file = %s\n', snirfName);
            % % fprintf('Full path = %s\n', filePath);
                        
            % HERE, RIGHT HERE: LOAD SNIRF
            acquired = SnirfClass(filePath);
            app.ds.snirf = acquired;

            % debug
            % copy "acquired" from app desginer to the base workspace, so can check in the command window
            assignin('base','acquired',acquired);

            if isempty(acquired.data)
                warning('SNIRF file empty');
                uialert(app.UIFigure, 'SNIRF file is empty', 'warning');
            else
                disp('SNIRF good.');
            end


            % Get channel:
            ml = app.ds.snirf.data.measurementList;

            if isempty(ml)
                uialert(app.UIFigure,'measurementList is empty.','Warning');
                return;
            end

            
            %% Create arrays to store Source (Tx) and Detector (Rx) IDs
            %% Each measurement will have one Tx and one Rx, I guess
            sourceArray = zeros( numel(ml), 1);
            detectorArray = zeros(numel(ml), 1);

            % Fill the arrays with IDs from each measurement
            for i = 1:(numel(ml))
                sourceArray(i) = ml(i).sourceIndex;
                detectorArray(i) = ml(i).detectorIndex;
            end
 

            % Combine Tx and Rx into one nx2 array
            % make a list of UNIQUE [Tx,Rx] pairs
            % keep only the first occurrence of each pair
            pairsRaw = [sourceArray detectorArray];
            pairs = []; % start with empty list

            for i = 1:size(pairsRaw, 1)
                isExist = false;
                row = pairsRaw(i,:);
                    
                % Check if this pair is already in 'pairs'
                for j = 1:size(pairs,1)
                    if all(row == pairs(j,:))
                        isExist = true;
                        break;
                    end
                end

                % If the pair is new, add it to the list
                if isExist == false
                    pairs = [pairs; row];
                end
            end


            %% Create label & map, update channel to ListBox of channel
            m = size(pairs,1);
            labels = strings(m,1);
            app.ds.channelMap = struct('label',{},'s',{},'d',{});
            
            for k = 1:m
                s = pairs(k,1); 
                d = pairs(k,2);
                labels(k) = sprintf('Tx%d–Rx%d', s, d);
        
                app.ds.channelMap(k).label = labels(k);
                app.ds.channelMap(k).s = s;
                app.ds.channelMap(k).d = d;
                
                % Debug print
                fprintf('Channel %d: \n', k);
                fprintf('  s = %d, d = %d \n', s, d);
                fprintf('  label = %s \n', labels(k));
                fprintf('\n\n\n');
            end

            %% updatea channelDropDown on GUI
            app.ChannelDropDown.Items = cellstr(labels(:));   % ensure column cellstr
            app.ChannelDropDown.Value = app.ChannelDropDown.Items{1};  % must exist in Items
            drawnow; % force GUI refresh to update channelDropDown


            %%update ListBox for showing like wavelength and Hbr...
            updateSignalList(app);


           app.ds.wls = app.ds.snirf.GetWls();
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)

            
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            updateSignalList(app);
            
        end

        % Clicked callback: SignalListBox
        function SignalListBoxClicked(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BrowseData = uibutton(app.UIFigure, 'push');
            app.BrowseData.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataButtonPushed, true);
            app.BrowseData.Text = 'Browse';
            app.DeviceLabel = uilabel(app.UIFigure);
            app.DeviceLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.UIFigure);
            app.DeviceDropDown.Items = {'GPU', 'CPU'};
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.DeviceDropDown.Value = 'GPU';
            app.RunImageReconExp = uibutton(app.UIFigure, 'push');
            app.RunImageReconExp.ButtonPushedFcn = createCallbackFcn(app, @RunImageReconExpButtonPushed, true);
            app.RunImageReconExp.Text = 'Run reconstruct';
            app.DatafilesLabel = uilabel(app.UIFigure);
            app.DatafilesLabel.Text = 'Subjects';
            app.ListBoxSubject = uilistbox(app.UIFigure);
            app.ListBoxSubject.Items = {};
            app.ListBoxSubject.Multiselect = 'on';
            app.ListBoxSubject.DoubleClickedFcn = createCallbackFcn(app, @ListBoxSubjectDoubleClicked, true);
            app.ListBoxSubject.Value = {};
            app.UseSSavgCheckBox = uicheckbox(app.UIFigure);
            app.UseSSavgCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSSavgCheckBoxValueChanged, true);
            app.UseSSavgCheckBox.Text = 'Use SS (avg)';
            app.UsedrifterCheckBox = uicheckbox(app.UIFigure);
            app.UsedrifterCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsedrifterCheckBoxValueChanged, true);
            app.UsedrifterCheckBox.Text = 'Use drifter';
            app.SpatiallyregularizationCheckBox = uicheckbox(app.UIFigure);
            app.SpatiallyregularizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpatiallyregularizationCheckBoxValueChanged, true);
            app.SpatiallyregularizationCheckBox.Text = 'Spatially regularization';
            app.AdvancedsettingButton = uibutton(app.UIFigure, 'push');
            app.AdvancedsettingButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedsettingButtonPushed, true);
            app.AdvancedsettingButton.Text = 'Advanced setting';
            app.ListsnrifListBoxLabel = uilabel(app.UIFigure);
            app.ListsnrifListBoxLabel.Text = 'List snrif';
            app.ListsnrifListBox = uilistbox(app.UIFigure);
            app.ListsnrifListBox.Items = {};
            app.ListsnrifListBox.DoubleClickedFcn = createCallbackFcn(app, @ListsnrifListBoxDoubleClicked, true);
            app.ListsnrifListBox.Value = {};
            app.PlotTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.RawdataButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.RawdataButton.Text = 'Raw data';
            app.RawdataButton.Value = true;
            app.ODButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ODButton.Text = 'OD';
            app.ConcButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ConcButton.Text = 'Conc';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SignalListBox = uilistbox(app.UIFigure);
            app.SignalListBox.Items = {};
            app.SignalListBox.ClickedFcn = createCallbackFcn(app, @SignalListBoxClicked, true);
            app.SignalListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end