classdef new_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        SaveSSDOTAsMenu             matlab.ui.container.Menu
        LoadSSDOTMenu               matlab.ui.container.Menu
        LogButton                   matlab.ui.control.Button
        ProcessButton               matlab.ui.control.Button
        GPUCheckBox                 matlab.ui.control.CheckBox
        ConditonDropDown            matlab.ui.control.DropDown
        ConditonDropDownLabel       matlab.ui.control.Label
        HRFCheckBox                 matlab.ui.control.CheckBox
        DataTree                    matlab.ui.container.Tree
        ProcessinglevelButtonGroup  matlab.ui.container.ButtonGroup
        SessButton                  matlab.ui.control.RadioButton
        RunButton                   matlab.ui.control.RadioButton
        SubjButton                  matlab.ui.control.RadioButton
        GroupButton                 matlab.ui.control.RadioButton
        SignalListBox               matlab.ui.control.ListBox
        PlotButton                  matlab.ui.control.Button
        ChannelDropDown             matlab.ui.control.DropDown
        ChannelDropDownLabel        matlab.ui.control.Label
        PlotTypeButtonGroup         matlab.ui.container.ButtonGroup
        ConcButton                  matlab.ui.control.RadioButton
        ODButton                    matlab.ui.control.RadioButton
        RawdataButton               matlab.ui.control.RadioButton
        BrowseData                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Property % Description
        AdvApp
        ds % data source
        cache % current process
        sel % user selection
        ssdotPipeline
        Target % can be run/session/subj/group
    end
    
    methods (Access = private)
        %% UI design layout:
        function setupLayout(app)
            app.UIFigure.Position = [80 80 1100 700];
        
            rootGrid = uigridlayout(app.UIFigure, [1 2]);
            rootGrid.ColumnWidth = {250, '1x'};
            rootGrid.RowHeight = {'1x'};
            rootGrid.Padding = [10 10 10 10];
            rootGrid.ColumnSpacing = 10;
        
            sidePanel = uipanel(rootGrid);
            sidePanel.BorderType = 'none';
            sidePanel.Layout.Row = 1;
            sidePanel.Layout.Column = 1;
        

            sideGrid = uigridlayout(sidePanel, [8 1]);
            sideGrid.RowHeight = {'1x', '5x', '6x', '5x', '1.75x', '3x', '1.25x', '2x'};
            sideGrid.Padding = [5 5 5 5];
            sideGrid.RowSpacing = 5;

        
            app.BrowseData.Parent = sideGrid;
            app.BrowseData.Layout.Row  = 1;
            app.BrowseData.Layout.Column = 1;
        
            app.DataTree.Parent = sideGrid;
            app.DataTree.Layout.Row = 2;
        
            app.ProcessinglevelButtonGroup.Parent  = sideGrid;
            app.ProcessinglevelButtonGroup.Layout.Row = 3;
            app.ProcessinglevelButtonGroup.Layout.Column = 1;
        
            app.PlotTypeButtonGroup.Parent = sideGrid;
            app.PlotTypeButtonGroup.Layout.Row = 4;
            app.PlotTypeButtonGroup.Layout.Column = 1;
        
            chanGrid = uigridlayout(sideGrid, [1 2]);   
            chanGrid.ColumnWidth = {'fit', '1x'};
            chanGrid.Padding = [0 5 0 5];
            chanGrid.ColumnSpacing = 5;
            chanGrid.Layout.Row = 5;
            chanGrid.Layout.Column = 1;
        
            app.ChannelDropDownLabel.Parent = chanGrid;
            app.ChannelDropDownLabel.Layout.Row = 1;
            app.ChannelDropDownLabel.Layout.Column = 1;
        
            app.ChannelDropDown.Parent  = chanGrid;
            app.ChannelDropDown.Layout.Row    = 1;
            app.ChannelDropDown.Layout.Column = 2;
        
            app.SignalListBox.Parent        = sideGrid;
            app.SignalListBox.Layout.Row    = 6;
            app.SignalListBox.Layout.Column = 1;
        
            hrfGrid = uigridlayout(sideGrid, [1 3]);
            hrfGrid.ColumnWidth = {'fit', 'fit', '1x'};
            hrfGrid.Padding  = [0 5 0 5];
            hrfGrid.ColumnSpacing = 5;
            hrfGrid.Layout.Row = 7;
            hrfGrid.Layout.Column = 1;
        
            app.HRFCheckBox.Parent  = hrfGrid;
            app.HRFCheckBox.Layout.Row  = 1;
            app.HRFCheckBox.Layout.Column = 1;
        
            app.ConditonDropDownLabel.Parent = hrfGrid;
            app.ConditonDropDownLabel.Layout.Row  = 1;
            app.ConditonDropDownLabel.Layout.Column = 2;
        
            app.ConditonDropDown.Parent  = hrfGrid;
            app.ConditonDropDown.Layout.Row = 1;
            app.ConditonDropDown.Layout.Column = 3;
        
            btnGrid = uigridlayout(sideGrid, [1 2]);
            btnGrid.ColumnWidth   = {'1x', '1x'};
            btnGrid.Padding   = [0 5 0 5];
            btnGrid.ColumnSpacing = 5;
            btnGrid.Layout.Row  = 8;
            btnGrid.Layout.Column = 1;
        
            app.ProcessButton.Parent  = btnGrid;
            app.ProcessButton.Layout.Row  = 1;
            app.ProcessButton.Layout.Column = 1;
        
            app.PlotButton.Parent  = btnGrid;
            app.PlotButton.Layout.Row  = 1;
            app.PlotButton.Layout.Column = 2;
        
        
            plotPanel = uipanel(rootGrid);
            plotPanel.Title  = 'Plot';
            plotPanel.FontSize  = 13;
            plotPanel.Layout.Row = 1;
            plotPanel.Layout.Column = 2;
        
            plotGrid = uigridlayout(plotPanel, [1 1]);
            plotGrid.Padding = [5 5 5 5];
        
            app.UIAxes.Parent = plotGrid;
            app.UIAxes.Layout.Row = 1;
            app.UIAxes.Layout.Column = 1;
        end


        %% This function update the "Signal List" box on the UI
        % It check which plot type the user selected Raw/OD/Conc.
        % e.g: user select Conc -> it show (like Hbo/HbR/HbT)
        % After creating this list, the function updates the SignalListBox on the UI 
        function updateSignalList(app)
            if isempty(app.ds) || ~isfield(app.ds,'firstRun')
                return;
            end
            plotType = app.PlotTypeButtonGroup.SelectedObject.Text;
            app.ds.waveLength = app.ds.firstRun.GetWls();
            setappdata(app.UIFigure, 'wavelength', app.ds.waveLength);

            items = {};
            data  = [];   % numeric indicecs

            % user select Raw/OD -> it will show (like 670nm, 850nm)
            if strcmp(plotType, 'Raw data') || strcmp(plotType, 'OD')
                for i = 1:numel(app.ds.waveLength)
                    items{i} = sprintf('%dnm', round(app.ds.waveLength(i)));
                    data(i) = i;
                end
                
            % user select Conc -> it show (like Hbo/HbR/HbT)
            elseif strcmp(plotType, 'Conc')
                items{1} = 'HbO';
                items{2} = 'HbR';
                items{3} = 'HbT';
                data = [0 1 2];
            end

            % update Signal List Box
            app.SignalListBox.Items = items;
            app.SignalListBox.ItemsData = data;
            app.SignalListBox.Value = data(1);
            drawnow;
        end
        

        %%  Parse channel label text "SxX–DxY" into numeric ID
        function [s,d] = parseChannelLabel(~,label)
            % extract 2 numbers: after 'Sx' and after 'Dx', with any non-digit separator between
            tok = regexp(label,'Sx(\d+)[^\d]+Dx(\d+)','tokens','once');
            s = str2double(tok{1});
            d = str2double(tok{2});
        end


        %% Parse the integer number before 'nm'
        function waveLengthIdx = nmLabelToIndex(app, nmLabel)
            waveLengthIdx = [];
            nm = sscanf(nmLabel, '%dnm');
            if isempty(nm) || isempty(app.ds.wls)
                waveLengthIdx = [];
                return;
            end
            % Find the first index where wavelength equals that number
            waveLengthIdx = find(app.ds.wls == nm, 1);
        end
   

        %% Get channel pair list Sx#-Dx#
        function getChannelList(app)
            % I pick first run to get channel list
            acq = app.ds.firstRun.acquired;
            acq.Load()
            assignin('base', 'acq', acq);
            ml = app.ds.firstRun.acquired.data.measurementList;

            if isempty(ml)
                uialert(app.UIFigure, 'measurementList is empty.', 'Error');
                return;
            end

            sourceArray = zeros(numel(ml), 1);
            detectorArray = zeros(numel(ml), 1);

            %fill the arrays
            for i = 1:(numel(ml))
                sourceArray(i) = ml(i).sourceIndex;
                detectorArray(i) = ml(i).detectorIndex;
            end

            % Combine Sx and Dx into one nx2 array
            % make a list of UNIQUE [Sx,Dx] pairs
            % keep only the first occurrence of each pair
            pairsRaw = [sourceArray detectorArray];
            pairs = []; % start with empty list

            for i = 1:size(pairsRaw, 1)
                isExist = false;
                row = pairsRaw(i,:);

                % check if this pair is already in 'pairs'
                for j = 1:size(pairs, 1)
                    if all(row == pairs(j,:))
                        isExist = true;
                        break;
                    end 
                end

                % If the pair is new, add it toe the list
                if isExist == false
                    pairs = [pairs; row];
                end
            end


            % create label and map, update channel to ListBox of channel
            rowSize = size(pairs, 1);
            channelMap(rowSize, 1) = struct('label', "", 'source', 0, 'detector', 0);

            for i = 1:rowSize
                source = pairs(i, 1);
                detector = pairs(i, 2);

                label = sprintf('Sx%d-Dx%d', source, detector);

                channelMap(i).label = label;
                channelMap(i).source = source;
                channelMap(i).detector = detector;

                %fprintf('Channel %d: s=%d, d=%d, label=%s\n', i, source, detector, label);
            end


            % update channelDropDown on GUI
            items = {channelMap.label}.';
            app.ChannelDropDown.Items = items;
            app.ChannelDropDown.Value = app.ChannelDropDown.Items{1};
            drawnow;

        end


        %%
        % Build the tree view like:
        % Group 
        %      Subject
        %             Session 
        %                    Run
        % Each node get a label (the output filename) and a NodeData struct that stores its Index 
        % [groupIdx subjIdx sessIdx runIdx]. later on figure out exactly which file the user clicked in the tree.
        function buildTreeFromGroup(app, group)
            ig = max(1, group.iGroup);
        
            groupNode = uitreenode(app.DataTree, ...
                "Text", char(group.GetOutputFilename()), ...
                "NodeData", struct('type','group','idx',[ig 0 0 0]));
        
            for isubj = 1:numel(group.subjs)
                subj = group.subjs(isubj);
                subjNode = uitreenode(groupNode, ...
                    "Text", char(subj.GetOutputFilename()), ...
                    "NodeData", struct('type','subj','idx',[ig isubj 0 0]));
        
                for isess = 1:numel(subj.sess)
                    sess = subj.sess(isess);
                    sessNode = uitreenode(subjNode, ...
                        "Text", char(sess.GetOutputFilename()), ...
                        "NodeData", struct('type','sess','idx',[ig isubj isess 0]));
        
                    for irun = 1:numel(sess.runs)
                        run = sess.runs(irun);
                        uitreenode(sessNode, ...
                            "Text", char(run.GetOutputFilename()), ...
                            "NodeData", struct('type','run','idx',[ig isubj isess irun]));
                    end
                    expand(sessNode);
                end
                expand(subjNode);
            end
            expand(groupNode);
        end


        function updateConditionList(app)
            stimList = app.ds.firstRun.GetStim();
            n = numel(stimList);
            items = {};

            for i = 1:n
                data = stimList(i).GetData();
                if isempty(data)
                    continue
                end
                items{end+1} = stimList(i).name;
            end

            %condNames = app.ds.firstRun.CondNames;
            %items = condNames;

            app.ConditonDropDown.Items = items;
            app.ConditonDropDown.Value = items{1};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup UI:
            setupLayout(app);
            
            % DEFAULT DEFAULT
            % Get root directory (where startup.m is located)
            rootPath = fileparts(fileparts(mfilename('fullpath')));
            
            % Add all necessary paths
            addpath(genpath(fullfile(rootPath, 'code')));
            addpath(genpath(fullfile(rootPath, 'GUI')));
            addpath(genpath(fullfile(rootPath, 'homer_function')));


            % SYNC UI
            app.sel.processingLevel = app.ProcessinglevelButtonGroup.SelectedObject.Text;
            
    
        end

        % Button pushed function: BrowseData
        function BrowseDataButtonPushed(app, event)
            %% Open a folder selection to allow user to choose(browse) the data folder
            %% Then after select will immediately load GroupResults.m data and show the subjects/group/run list
            
            % let user pick Homer output folder eg: ST001 folder
            folderPath = uigetdir(pwd, 'Select the output homer folder');

            if isequal(folderPath,0)
                return; % user hit cancel
            end

            % ST001/derivatives/homer/groupResults.mat
            groupPath = fullfile(folderPath, 'derivatives', 'homer', 'groupResults.mat');
            % guard
            if ~isfile(groupPath)
                uialert(app.UIFigure, sprintf('Could not find groupResults.mat at:\n%s\n\nPlease select a valid Homer output folder.', groupPath), 'invalid Folder');
                return;
            end
            S = load(groupPath, 'group');
            app.ds.groupResult = S.group;
            assignin('base','groupResult', app.ds.groupResult);

            
            % After load groupResults.mat, reset all run/session paths to your current folder before calling
            loadAllFile(folderPath, app.ds.groupResult);
            app.ds.firstRun = app.ds.groupResult(1).subjs(1).sess(1).runs(1);
            assignin('base','firstRun',app.ds.firstRun); % load into workspace for testing/debugging
            assignin('base', 'group', app.ds.groupResult);


            % Build the DataTree UI to display hierarchy  
            % (Group → Subject → Session → Run) in the app’s tree panel 
            buildTreeFromGroup(app, app.ds.groupResult);


            getChannelList(app);
            updateSignalList(app);
            updateConditionList(app);
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            selectedText = app.PlotTypeButtonGroup.SelectedObject.Text;
            if isempty(app.ds) || ~isfield(app.ds,'firstRun')
                return;
            end
            
            switch lower(selectedText)
                case 'raw data'
                    app.HRFCheckBox.Value = false;
                    app.HRFCheckBox.Enable = 'off';
                    app.ConditonDropDown.Enable = 'off';
                case 'od'
                    dodAvg = app.ds.firstRun.procStream.output.GetVar('dodAvg');
                    if isempty(dodAvg)
                        app.HRFCheckBox.Value = false;
                        app.HRFCheckBox.Enable = 'off';
                        app.ConditonDropDown.Enable = 'off';
                    else
                        app.HRFCheckBox.Enable = 'on';
                    end
                case 'conc'
                    dcAvg =  app.ds.firstRun.procStream.output.GetVar('dcAvg');
                    if isempty(dcAvg)
                        app.HRFCheckBox.Value = false;
                        app.HRFCheckBox.Enable = 'off';
                        app.ConditonDropDown.Enable = 'off';
                    else
                        app.HRFCheckBox.Enable = 'on';
                    end
            end
            updateSignalList(app);
            
        end

        % Selection changed function: ProcessinglevelButtonGroup
        function ProcessinglevelButtonGroupSelectionChanged(app, event)
            app.sel.processingLevel = app.ProcessinglevelButtonGroup.SelectedObject.Text;
            app.DataTree.SelectedNodes =[];
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %% Wrap everything to check before hit plot button
            %% Basic guards
            if ~isfield(app.ds,'groupResult') || isempty(app.ds.groupResult)
                uialert(app.UIFigure,'Load a dataset first','No Data');
                return;
            end
            if ~isfield(app.sel,'file')
                uialert(app.UIFigure,'Select a RUN in the tree.','Info');
                return;
            end
            if isempty(app.ChannelDropDown.Value)
                uialert(app.UIFigure,'Pick a channel (Sx–Dx).','Info'); 
                return;
            end
            if isempty(app.SignalListBox.Value)
                uialert(app.UIFigure,'Pick at least one signal to plot.','Info'); 
                return;
            end

            cla(app.UIAxes, 'reset');


            % parse dropdown text "Sx#-Dx#" into NUMERIC IDs
            [app.sel.source, app.sel.detector] = app.parseChannelLabel(app.ChannelDropDown.Value);
            app.sel.waveLengthLabel = app.SignalListBox.Items;
            app.sel.selectSignal = app.SignalListBox.Value;
            app.sel.condName = app.ConditonDropDown.Value;

            % user slect processing level type and plot type
            ProcessLevelType = app.ProcessinglevelButtonGroup.SelectedObject.Text;
            PlotType = app.PlotTypeButtonGroup.SelectedObject.Text;

            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            app.cache.legendLabels = {};


            % Decide what to plot based on "Process Level" (Run, Session, Subject, Group) 
            % and "Plot Type" (OD or Conc).  
            % 
            %  If HRF checkbox is checked => plot averaged signal (dodAvg or dcAvg).  
            %  If HRF checkbox is not checked => plot continuous signal or every run 
            %  Run level = plot just the selected run
            %  Session level = plot all runs in that session (loop through runs) 
            %  Subject level = loop through all sessions + runs for one subject
            %  Group level = loop through all subjects, sessions, and runs 

            switch ProcessLevelType
                %% RUN LEVEL
                case 'Run'
                    % get the actual run object based on selection in tree
                    run = app.ds.groupResult.subjs(app.sel.file.subjIdx).sess(app.sel.file.sessIdx).runs(app.sel.file.runIdx);
                    switch PlotType
                        case 'Raw data'
                            plotRaw(app.UIAxes, run, app.sel);
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Raw');
                            title(app.UIAxes, sprintf('Raw — Sx%d–Dx%d', app.sel.source, app.sel.detector));
                        case 'OD'
                            % If HRF checkbox is ON => plot averaged OD
                            % If OFF => plot continuous OD signal
                            if (app.HRFCheckBox.Value == true)
                                plotODAvg(app.UIAxes, run, app.sel);
                            else
                                plotOD(app.UIAxes, run, app.sel);
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Intensity (a.u.)');
                            title(app.UIAxes, sprintf('Optical Density — Sx%d–Dx%d', app.sel.source, app.sel.detector));

                        case 'Conc'
                            % Same logic but for concentrations (HbO/HbR/HbT)
                            if (app.HRFCheckBox.Value == true)
                                plotConcAvg(app.UIAxes, run, app.sel);
                            else
                                plotConc(app.UIAxes, run, app.sel);
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes, sprintf('(\\M = mol/L'));
                            title(app.UIAxes, sprintf('Conccentration — Sx%d–Dx%d', app.sel.source, app.sel.detector));
                    end


                %% Session level
                case 'Sess'
                    sess = app.ds.groupResult.subjs(app.sel.file.subjIdx).sess(app.sel.file.sessIdx);
                    switch PlotType
                        case 'Raw data'
                            for i = 1:numel(sess.runs)
                                plotRaw(app.UIAxes, sess.runs(i), app.sel);
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Raw');
                            title(app.UIAxes, sprintf('Raw — Sx%d–Dx%d', app.sel.source, app.sel.detector));
                        case 'OD'
                            if (app.HRFCheckBox.Value == true)
                                plotODAvg(app.UIAxes, sess, app.sel);
                            else
                                for i = 1:numel(sess.runs)
                                    plotOD(app.UIAxes, sess.runs(i), app.sel);
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Intensity (a.u.)');
                            title(app.UIAxes, sprintf('Optical Density — Sx%d–Dx%d', app.sel.source, app.sel.detector));

                        case 'Conc'
                            if (app.HRFCheckBox.Value == true)
                                plotConcAvg(app.UIAxes, sess, app.sel);
                            else %HRF = 0 uncheck
                                for i = 1:numel(sess.runs)
                                    plotConc(app.UIAxes, sess.runs(i), app.sel);
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes, sprintf('(\\M = mol/L'));
                            title(app.UIAxes, sprintf('Conc — Sx%d–Dx%d', app.sel.source, app.sel.detector));
                    end
                   

                %% Subject level
                case 'Subj'
                    subjs = app.ds.groupResult.subjs(app.sel.file.subjIdx);
                    switch PlotType
                        case 'Raw data'
                            for i  = 1:numel(subjs.sess)
                                for j = 1:numel(subjs.sess(i).runs)
                                    plotRaw(app.UIAxes, subjs.sess(i).runs(j), app.sel);
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Raw');
                            title(app.UIAxes, sprintf('Raw — Sx%d–Dx%d', app.sel.source, app.sel.detector));
                            
                        case 'OD'
                            % HRF ON = averaged dodAvg
                            % HRF OFF = plot every run in that subj
                            if (app.HRFCheckBox.Value == true)
                                plotODAvg(app.UIAxes, subjs, app.sel);
                            else % HRF = OFF
                                for i = 1:numel(subjs.sess)
                                    for j = 1:numel(subjs.sess(i).runs)
                                        plotOD(app.UIAxes, subjs.sess(i).runs(j), app.sel);
                                    end
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Intensity (a.u.)');
                            title(app.UIAxes, sprintf('Optical Density — Sx%d–Dx%d', app.sel.source, app.sel.detector));
                            
                        case 'Conc'
                            if (app.HRFCheckBox.Value == true)
                                plotConcAvg(app.UIAxes, subjs, app.sel);
                            else
                                for i = 1:numel(subjs.sess)
                                    for j =1:numel(subjs.sess(i).runs)
                                        plotConc(app.UIAxes, subjs.sess(i).runs(j), app.sel)
                                    end
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes, sprintf('(\\M = mol/L'));
                            title(app.UIAxes, sprintf('Conc — Sx%d–Dx%d', app.sel.source, app.sel.detector));
                    end
                    

                %% GROUP LEVEL
                case 'Group'
                    group = app.ds.groupResult;
                    switch PlotType
                        case 'Raw data'
                            for i = 1:numel(group.subjs)
                                for j = 1:numel(group.subjs(i).sess)
                                    for k = 1:numel(group.subjs(i).sess(j).runs)
                                        plotRaw(app.UIAxes, group.subjs(i).sess(j).runs(k), app.sel);
                                    end
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Raw');
                            title(app.UIAxes, sprintf('Raw — Sx%d–Dx%d', app.sel.source, app.sel.detector));
                        case 'OD'
                            if (app.HRFCheckBox.Value == true)
                                plotODAvg(app.UIAxes, group, app.sel);
                            else
                                for i = 1:numel(group.subjs)
                                    for j = 1:numel(group.subjs(i).sess)
                                        for k = 1:numel(group.subjs(i).sess(j).runs)
                                            plotOD(app.UIAxes, group.subjs(i).sess(j).runs(k), app.sel);
                                        end
                                    end
                                end
                            end
                                            
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Intensity (a.u.)');
                            title(app.UIAxes, sprintf('Optical Density — Sx%d–Dx%d', app.sel.source, app.sel.detector));
                            
                        case 'Conc'
                            if (app.HRFCheckBox.Value == true)
                                plotConcAvg(app.UIAxes, group, app.sel);
                            else
                                for i = 1:numel(group.subjs)
                                    for j = 1:numel(group.subjs(i).sess)
                                        for k = 1:numel(group.subjs(i).sess(j).runs)
                                            plotConc(app.UIAxes, group.subjs(i).sess(j).runs(i), app.sel);
                                        end
                                    end
                                end
                            end
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes, sprintf('(\\M = mol/L'));
                            title(app.UIAxes, sprintf('Conc — Sx%d–Dx%d', app.sel.source, app.sel.detector));
                    end
            end
            legend(app.UIAxes,'show','Location','northeast');
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            grid(app.UIAxes,'on');
            hold(app.UIAxes,'off');
            
        end

        % Selection changed function: DataTree
        function DataTreeSelectionChanged(app, event)
            % Handle selection in the DataTree (group/subj/sess/run)
            % Get whatever node the user just clicked on Data tree list
            selectedNodes = event.SelectedNodes;
            nodeData = selectedNodes.NodeData;

            % Figure out what level the user *wants* to work at (from processing level buttons)
            requiredLevel = lower(strtrim(app.ProcessinglevelButtonGroup.SelectedObject.Text));

            % If they clicked the wrong kind of node (ex: processing level = run but clicked subj folder on data list)
            if ~strcmp(nodeData.type, requiredLevel)
                app.DataTree.SelectedNodes = [];
                uialert(app.UIFigure, "Please select a " + requiredLevel + " file.", "Wrong level");
                return;
            end

            % Save the selected node info into app.sel.file for later use 
            % nodeData.idx = [groupIndex subjIndex sessIndex runIndex]
            app.sel.file = struct();
            app.sel.file.groupIdx = nodeData.idx(1);
            app.sel.file.subjIdx = nodeData.idx(2);
            app.sel.file.sessIdx = nodeData.idx(3);
            app.sel.file.runIdx = nodeData.idx(4);

            switch app.sel.processingLevel
                case 'Run'
                    app.Target = app.ds.groupResult.subjs(app.sel.file.subjIdx).sess(app.sel.file.sessIdx).runs(app.sel.file.runIdx);
                case 'Sess'
                    app.Target = app.ds.groupResult.subjs(app.sel.file.subjIdx).sess(app.sel.file.sessIdx);
                case 'Subj'
                    app.Target = app.ds.groupResult.subjs(app.sel.file.subjIdx);
                case 'Group'
                    app.Target = app.ds.groupResult;
            end

            assignin('base', 'Target', app.Target);


        end

        % Value changed function: HRFCheckBox
        function HRFCheckBoxValueChanged(app, event)
            if app.HRFCheckBox.Value
                app.ConditonDropDown.Enable = 'on';
            else
                app.ConditonDropDown.Enable = 'off';
            end
        end

        % Clicked callback: SignalListBox
        function SignalListBoxClicked(app, event)
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)

            try
                if isempty(app.Target)
                    uialert(app.UIFigure, 'Please select a file from the tree first', 'No Selection');
                    LoggerWindow.log('warning', 'No target selected');
                    return;
                end
        
                % Check processing level to decide which window to open
                processingLevel = app.ProcessinglevelButtonGroup.SelectedObject.Text;
        
                switch processingLevel
                    case 'Run'
                        % Run level -> open SSDOT Pipeline for single run processing
                        if isempty(app.Target.ssdot)
                            uialert(app.UIFigure, 'Initialize ssdot first', 'Error');
                            LoggerWindow.log('Error', 'ssdot object hasnt created');
                            return;
                        end
                        
                        % Check if pipeline window exists AND is still valid
                        if isempty(app.ssdotPipeline) || ~isvalid(app.ssdotPipeline) || ~app.ssdotPipeline.isWindowValid()
                            app.ssdotPipeline = SSDOTPipelineWindow.open(app, app.Target.ssdot);
                        else
                            if app.ssdotPipeline.ssdot ~= app.Target.ssdot
                                str = sprintf('%s %d', app.Target.type, app.Target.iRun);
                                choice = uiconfirm(app.UIFigure, ...
                                    sprintf('SSDOT Pipeline is currently open for a different %s. What would you like to do?', str), ...
                                    'Switch Target?', ...
                                    'Options', {'Switch to new', 'Keep current'}, ...
                                    'DefaultOption', 1, ...
                                    'CancelOption', 2, ...
                                    'Icon', 'question');
        
                                if strcmp(choice, 'Switch to new')
                                    app.ssdotPipeline.close();
                                    app.ssdotPipeline = SSDOTPipelineWindow.open(app, app.Target.ssdot);
                                    LoggerWindow.log('info', sprintf('Switched SSDOT Pipeline to new %s', str));
                                else
                                    app.ssdotPipeline.bringToFront();
                                    LoggerWindow.log('info', 'Kept current SSDOT Pipeline window');
                                end
                            else
                                app.ssdotPipeline.bringToFront();
                            end
                        end
        
                    case {'Sess', 'Subj', 'Group'}
                        % Higher level = open average window for multi-run analysis
                        avgWindow.open(app.Target);
                        LoggerWindow.log('info', sprintf('Opened average window for %s level', processingLevel));
                end
                
            catch ME
                LoggerWindow.log('error', ME.message);
                uialert(app.UIFigure, ME.message, 'Open Window Error');
                rethrow(ME);
            end

        end

        % Menu selected function: SaveSSDOTAsMenu
        function SaveSSDOTAsMenuSelected(app, event)
            if isempty(app.ssdot) || ~isa(app.ssdot, 'ssdotClass')
                uialert(app.UIFigure, 'No SSDOT session to save.', 'Save Error');
                return;
            end
        
            [file, path] = uiputfile('*.mat', 'Save SSDOT session as');
            if isequal(file,0); return; end
        
            fullFileName = fullfile(path, file);
        
            try
                app.ssdot.saveToFile(fullFileName);
            catch ME
                uialert(app.UIFigure, ME.message, 'Save Failed');
            end
        end

        % Value changed function: GPUCheckBox
        function GPUCheckBoxValueChanged(app, event)
            if app.GPUCheckBox.Value
                app.ssdot.cfg.device = 'gpu';
            else
                app.ssdot.cfg.device = 'cpu';
            end
            
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            LoggerWindow.show();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            LoggerWindow.deleteInstance();
            delete(app);
        end

        % Callback function: not associated with a component
        function ButtonPushed(app, event)
            app.ssdotPipeline = avgWindow.open(app.Target);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SaveSSDOTAsMenu = uimenu(app.Menu);
            app.SaveSSDOTAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSSDOTAsMenuSelected, true);
            app.SaveSSDOTAsMenu.Text = 'Save SSDOT As';
            app.LoadSSDOTMenu = uimenu(app.Menu);
            app.LoadSSDOTMenu.Text = 'Load SSDOT';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BrowseData = uibutton(app.UIFigure, 'push');
            app.BrowseData.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataButtonPushed, true);
            app.BrowseData.Text = 'Load Homer derivitives';
            app.PlotTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.RawdataButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.RawdataButton.Text = 'Raw data';
            app.RawdataButton.Value = true;
            app.ODButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ODButton.Text = 'OD';
            app.ConcButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ConcButton.Text = 'Conc';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.Value = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SignalListBox = uilistbox(app.UIFigure);
            app.SignalListBox.Items = {};
            app.SignalListBox.Multiselect = 'on';
            app.SignalListBox.ClickedFcn = createCallbackFcn(app, @SignalListBoxClicked, true);
            app.SignalListBox.Value = {};
            app.ProcessinglevelButtonGroup = uibuttongroup(app.UIFigure);
            app.ProcessinglevelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProcessinglevelButtonGroupSelectionChanged, true);
            app.ProcessinglevelButtonGroup.Title = 'Processing level';
            app.GroupButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.GroupButton.Text = 'Group';
            app.SubjButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.SubjButton.Text = 'Subj';
            app.RunButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.RunButton.Text = 'Run';
            app.RunButton.Value = true;
            app.SessButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.SessButton.Text = 'Sess';
            app.DataTree = uitree(app.UIFigure);
            app.DataTree.SelectionChangedFcn = createCallbackFcn(app, @DataTreeSelectionChanged, true);
            app.HRFCheckBox = uicheckbox(app.UIFigure);
            app.HRFCheckBox.ValueChangedFcn = createCallbackFcn(app, @HRFCheckBoxValueChanged, true);
            app.HRFCheckBox.Text = 'HRF';
            app.ConditonDropDownLabel = uilabel(app.UIFigure);
            app.ConditonDropDownLabel.Text = 'Conditon';
            app.ConditonDropDown = uidropdown(app.UIFigure);
            app.ConditonDropDown.Items = {};
            app.ConditonDropDown.Enable = 'off';
            app.ConditonDropDown.Value = {};
            app.GPUCheckBox = uicheckbox(app.UIFigure);
            app.GPUCheckBox.ValueChangedFcn = createCallbackFcn(app, @GPUCheckBoxValueChanged, true);
            app.GPUCheckBox.Text = 'GPU';
            app.GPUCheckBox.Value = true;
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'ssdot';
            app.LogButton = uibutton(app.UIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = 'Log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end